classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        hidden                  matlab.ui.control.NumericEditField
        sentence                matlab.ui.control.EditField
        AttemptsEditField       matlab.ui.control.NumericEditField
        AttemptsEditFieldLabel  matlab.ui.control.Label
        Reset                   matlab.ui.control.Button
        GuessButton             matlab.ui.control.Button
        Label                   matlab.ui.control.Label
        inputField              matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.hidden.Value = randi([1 100]);
        end

        % Value changed function: inputField
        function inputFieldValueChanged(app, event)
            value = app.inputField.Value;
            
        end

        % Value changed function: sentence
        function sentenceValueChanged(app, event)
            value = app.sentence.Value;
            
        end

        % Button pushed function: GuessButton
        function GuessButtonPushed(app, event)
            guess = str2num(app.inputField.Value);


    if isempty(guess)
        app.sentence.Value = 'Enter a number first!';
        return;
    end

    temp = app.AttemptsEditField.Value;
    app.AttemptsEditField.Value = temp + 1 ;

    if guess > app.hidden.Value
        app.sentence.Value  = 'Too High 🔼';

    elseif guess < app.hidden.Value
        app.sentence.Value  = 'Too Low 🔽';

    else
        app.sentence.Value = 'You Win 🎉';
        return;
    end

    if app.AttemptsEditField.Value >= 5
        app.sentence.Value  = "Game Over ❌ ( " + num2str(app.hidden.Value) + " )";
    end
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            app.inputField.Value = "";
            app.AttemptsEditField.Value = 0 ;
            app.sentence.Value = "starting guess ....";
            startupFcn(app);

        end

        % Value changed function: AttemptsEditField
        function AttemptsEditFieldValueChanged(app, event)
            value = app.AttemptsEditField.Value;
            
        end

        % Value changed function: hidden
        function hiddenValueChanged(app, event)
            value = app.hidden.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.inputField = uieditfield(app.UIFigure, 'text');
            app.inputField.ValueChangedFcn = createCallbackFcn(app, @inputFieldValueChanged, true);
            app.inputField.Tag = 'input_area';
            app.inputField.Placeholder = '1 : 100';
            app.Label = uilabel(app.UIFigure);
            app.GuessButton = uibutton(app.UIFigure, 'push');
            app.GuessButton.ButtonPushedFcn = createCallbackFcn(app, @GuessButtonPushed, true);
            app.GuessButton.Text = 'Guess';
            app.Reset = uibutton(app.UIFigure, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = 'Reset';
            app.AttemptsEditFieldLabel = uilabel(app.UIFigure);
            app.AttemptsEditFieldLabel.Text = 'Attempts =';
            app.AttemptsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AttemptsEditField.ValueChangedFcn = createCallbackFcn(app, @AttemptsEditFieldValueChanged, true);
            app.sentence = uieditfield(app.UIFigure, 'text');
            app.sentence.ValueChangedFcn = createCallbackFcn(app, @sentenceValueChanged, true);
            app.sentence.Value = 'starting guess ....';
            app.hidden = uieditfield(app.UIFigure, 'numeric');
            app.hidden.ValueChangedFcn = createCallbackFcn(app, @hiddenValueChanged, true);
            app.hidden.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end